classdef EV3SimGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ECE756EV3GUIUIFigure           matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        ManualMenu                     matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        TrackImage                     matlab.ui.control.Image
        Vehicle1_RedPanel              matlab.ui.container.Panel
        Vehicle1_PositionPanel         matlab.ui.container.Panel
        Vehicle1_X                     matlab.ui.control.NumericEditField
        Vehicle1_XmmLabel              matlab.ui.control.Label
        Vehicle1_Y                     matlab.ui.control.NumericEditField
        Vehicle1_YmmLabel              matlab.ui.control.Label
        Vehicle1_TH                    matlab.ui.control.NumericEditField
        Vehicle1_YawdegLabel           matlab.ui.control.Label
        Vehicle1_SensorsPanel          matlab.ui.container.Panel
        BatterymVGauge_4Label          matlab.ui.control.Label
        Vehicle1_BatterymVGauge        matlab.ui.control.SemicircularGauge
        LeftRLIGauge_4Label            matlab.ui.control.Label
        Vehicle1_LeftRLIGauge          matlab.ui.control.LinearGauge
        RightRLIGauge_4Label           matlab.ui.control.Label
        Vehicle1_RightRLIGauge         matlab.ui.control.LinearGauge
        UltrasoundcmGauge_4Label       matlab.ui.control.Label
        Vehicle1_UltrasoundcmGauge     matlab.ui.control.LinearGauge
        Vehicle1_PerformancePanel      matlab.ui.container.Panel
        Vehicle1_C1                    matlab.ui.control.NumericEditField
        Vehicle1_C1LapTimeLabel        matlab.ui.control.Label
        Vehicle1_C3                    matlab.ui.control.NumericEditField
        Vehicle1_C3SmoothnessLabel     matlab.ui.control.Label
        Vehicle1_C2                    matlab.ui.control.NumericEditField
        Vehicle1_C2TrackingErrorLabel  matlab.ui.control.Label
        Vehicle1_ControlsPanel         matlab.ui.container.Panel
        VisibleSwitch_3Label_2         matlab.ui.control.Label
        Vehicle1_VisibleSwitch         matlab.ui.control.Switch
        Vehicle1_DriveButton           matlab.ui.control.Button
        Vehicle1_StopButton            matlab.ui.control.Button
        Vehicle1_ParkButton            matlab.ui.control.Button
        Vehicle2_GreenPanel            matlab.ui.container.Panel
        Vehicle2_PositionPanel         matlab.ui.container.Panel
        Vehicle2_X                     matlab.ui.control.NumericEditField
        Vehicle2_XmmLabel              matlab.ui.control.Label
        Vehicle2_Y                     matlab.ui.control.NumericEditField
        Vehicle2_YmmLabel              matlab.ui.control.Label
        Vehicle2_TH                    matlab.ui.control.NumericEditField
        Vehicle2_YawdegLabel           matlab.ui.control.Label
        Vehicle2_SensorsPanel          matlab.ui.container.Panel
        BatterymVGauge_5Label          matlab.ui.control.Label
        Vehicle2_BatterymVGauge        matlab.ui.control.SemicircularGauge
        LeftRLIGauge_5Label            matlab.ui.control.Label
        Vehicle2_LeftRLIGauge          matlab.ui.control.LinearGauge
        RightRLIGauge_5Label           matlab.ui.control.Label
        Vehicle2_RightRLIGauge         matlab.ui.control.LinearGauge
        UltrasoundcmGauge_5Label       matlab.ui.control.Label
        Vehicle2_UltrasoundcmGauge     matlab.ui.control.LinearGauge
        Vehicle2_PerformancePanel      matlab.ui.container.Panel
        Vehicle2_C1                    matlab.ui.control.NumericEditField
        Vehicle2_C1LapTimeLabel        matlab.ui.control.Label
        Vehicle2_C3                    matlab.ui.control.NumericEditField
        Vehicle2_C3SmoothnessLabel     matlab.ui.control.Label
        Vehicle2_C2                    matlab.ui.control.NumericEditField
        Vehicle2_C2TrackingErrorLabel  matlab.ui.control.Label
        Vehicle2_ControlsPanel         matlab.ui.container.Panel
        VisibleSwitch_3Label_3         matlab.ui.control.Label
        Vehicle2_VisibleSwitch         matlab.ui.control.Switch
        Vehicle2_DriveButton           matlab.ui.control.Button
        Vehicle2_StopButton            matlab.ui.control.Button
        Vehicle2_ParkButton            matlab.ui.control.Button
        Vehicle3_BluePanel             matlab.ui.container.Panel
        Vehicle3_PositionPanel         matlab.ui.container.Panel
        Vehicle3_X                     matlab.ui.control.NumericEditField
        Vehicle3_XmmLabel              matlab.ui.control.Label
        Vehicle3_Y                     matlab.ui.control.NumericEditField
        Vehicle3_YmmLabel              matlab.ui.control.Label
        Vehicle3_TH                    matlab.ui.control.NumericEditField
        Vehicle3_YawdegLabel           matlab.ui.control.Label
        Vehicle3_SensorsPanel          matlab.ui.container.Panel
        BatterymVGauge_6Label          matlab.ui.control.Label
        Vehicle3_BatterymVGauge        matlab.ui.control.SemicircularGauge
        LeftRLIGauge_6Label            matlab.ui.control.Label
        Vehicle3_LeftRLIGauge          matlab.ui.control.LinearGauge
        RightRLIGauge_6Label           matlab.ui.control.Label
        Vehicle3_RightRLIGauge         matlab.ui.control.LinearGauge
        UltrasoundcmGauge_6Label       matlab.ui.control.Label
        Vehicle3_UltrasoundcmGauge     matlab.ui.control.LinearGauge
        Vehicle3_PerformancePanel      matlab.ui.container.Panel
        Vehicle3_C1                    matlab.ui.control.NumericEditField
        Vehicle3_C1LapTimeLabel        matlab.ui.control.Label
        Vehicle3_C3                    matlab.ui.control.NumericEditField
        Vehicle3_C3SmoothnessLabel     matlab.ui.control.Label
        Vehicle3_C2                    matlab.ui.control.NumericEditField
        Vehicle3_C2TrackingErrorLabel  matlab.ui.control.Label
        Vehicle3_ControlsPanel         matlab.ui.container.Panel
        VisibleSwitch_3Label_4         matlab.ui.control.Label
        Vehicle3_VisibleSwitch         matlab.ui.control.Switch
        Vehicle3_DriveButton           matlab.ui.control.Button
        Vehicle3_StopButton            matlab.ui.control.Button
        Vehicle3_ParkButton            matlab.ui.control.Button
        Vehicle4_PurplePanel           matlab.ui.container.Panel
        Vehicle4PositionPanel          matlab.ui.container.Panel
        Vehicle4_X                     matlab.ui.control.NumericEditField
        Vehicle4_XmmLabel              matlab.ui.control.Label
        Vehicle4_Y                     matlab.ui.control.NumericEditField
        Vehicle4_YmmLabel              matlab.ui.control.Label
        Vehicle4_TH                    matlab.ui.control.NumericEditField
        Vehicle4_YawdegLabel           matlab.ui.control.Label
        Vehicle4SensorsPanel           matlab.ui.container.Panel
        BatterymVGauge_7Label          matlab.ui.control.Label
        Vehicle4_BatterymVGauge        matlab.ui.control.SemicircularGauge
        LeftRLIGauge_7Label            matlab.ui.control.Label
        Vehicle4_LeftRLIGauge          matlab.ui.control.LinearGauge
        RightRLIGauge_7Label           matlab.ui.control.Label
        Vehicle4_RightRLIGauge         matlab.ui.control.LinearGauge
        UltrasoundcmGauge_7Label       matlab.ui.control.Label
        Vehicle4_UltrasoundcmGauge     matlab.ui.control.LinearGauge
        Vehicle4_PerformancePanel      matlab.ui.container.Panel
        Vehicle4_C1                    matlab.ui.control.NumericEditField
        Vehicle4_C1LapTimeLabel        matlab.ui.control.Label
        Vehicle4_C3                    matlab.ui.control.NumericEditField
        Vehicle4_C3SmoothnessLabel     matlab.ui.control.Label
        Vehicle4_C2                    matlab.ui.control.NumericEditField
        Vehicle4_C2TrackingErrorLabel  matlab.ui.control.Label
        Vehicle4_ControlsPanel         matlab.ui.container.Panel
        VisibleSwitch_3Label_5         matlab.ui.control.Label
        Vehicle4_VisibleSwitch         matlab.ui.control.Switch
        Vehicle4_DriveButton           matlab.ui.control.Button
        Vehicle4_StopButton            matlab.ui.control.Button
        Vehicle4_ParkButton            matlab.ui.control.Button
        GeneralControlsPanel           matlab.ui.container.Panel
        SimulinkPanel                  matlab.ui.container.Panel
        StartSimulinkButton            matlab.ui.control.Button
        StopSimulinkButton             matlab.ui.control.Button
        LeaderSelectionButtonGroup     matlab.ui.container.ButtonGroup
        Vehicle1_LeaderButton          matlab.ui.control.RadioButton
        Vehicle2_LeaderButton          matlab.ui.control.RadioButton
        Vehicle3_LeaderButton          matlab.ui.control.RadioButton
        Vehicle4_LeaderButton          matlab.ui.control.RadioButton
        NoteallotherenabledvehiclesarefollowersLabel  matlab.ui.control.Label
        DebugButton                    matlab.ui.control.Button
        GeneralControlsPanel_2         matlab.ui.container.Panel
        StartAllCarsButton             matlab.ui.control.Button
        StopAllCarsButton              matlab.ui.control.Button
        PlaceVehicle1Button            matlab.ui.control.Button
        PlaceVehicle2Button            matlab.ui.control.Button
        PlaceVehicle3Button            matlab.ui.control.Button
        PlaceVehicle4Button            matlab.ui.control.Button
    end

    
    properties (Access = private)
        % Background timer that forces more frequent GUI updates
        uiTimer = timer('StartDelay', 2, 'Period', .1, 'TasksToExecute', Inf, 'ExecutionMode', 'fixedRate');
        last_draw_time = tic;
        %updating_gui = false;
        %background = imresize(imread('map.png'), 1/5, 'bilinear'); % downsample image so that it doesn't take ages to render
        %rendering = false; % Used to toggle whether simulation is rendering
        Vehicle1_image;
        Vehicle2_image;
        Vehicle3_image;
        Vehicle4_image;
        Vehicle5_image;
        
        track_xscale = 149.6898563;
        track_yscale = 145.4882667;
        track_xoffset = 466;
        track_yoffset = 48;
        track_vehicle_xoffset = 12;
        track_vehicle_yoffset = 12;
        placingVehicle1 = false; % flags used to manually place the vehicle on the graph
        placingVehicle2 = false;
        placingVehicle3 = false;
        placingVehicle4 = false;
    end
    
    methods (Access = private)
        % Executed every timer tick. Used to force the GUI to update with
        % new data.
        function timerTick(app)
            %disp(toc(app.last_draw_time))
            %app.last_draw_time = tic;
            updateGraph(app);
        end
        
        function updateGraph(app)
            % Get vehicle positions from GUI components
            x_Vehicle1  = cast(app.Vehicle1_X.Value,  'single') / 1000.0;
            y_Vehicle1  = cast(app.Vehicle1_Y.Value,  'single') / 1000.0;
            th_Vehicle1 = cast(app.Vehicle1_TH.Value, 'single');
            x_Vehicle2  = cast(app.Vehicle2_X.Value,  'single') / 1000.0;
            y_Vehicle2  = cast(app.Vehicle2_Y.Value,  'single') / 1000.0;
            th_Vehicle2 = cast(app.Vehicle2_TH.Value, 'single');
            x_Vehicle3  = cast(app.Vehicle3_X.Value,  'single') / 1000.0;
            y_Vehicle3  = cast(app.Vehicle3_Y.Value,  'single') / 1000.0;
            th_Vehicle3 = cast(app.Vehicle3_TH.Value, 'single');
            
            % Update vehicle1 image location on track
            x_Vehicle1 = x_Vehicle1 + 2.37591; % Offset by x/y position at bottom left of track
            y_Vehicle1 = y_Vehicle1 + 0.29887; % Offset by x/y position at bottom left of track
            x_pixel_Vehicle1 = app.track_xoffset - app.track_vehicle_xoffset + x_Vehicle1 * app.track_xscale; % Convert to pixel location of center of vehicle image
            y_pixel_Vehicle1 = app.track_yoffset - app.track_vehicle_yoffset + y_Vehicle1 * app.track_yscale;
            rotatedImage = imrotate(imread('EV1.png'), th_Vehicle1, "bilinear", "crop"); % Rotate image - TODO: try to find a way to rotate that looks better!
            app.Vehicle1_image.ImageSource = rotatedImage; % update EV image with new rotation
            app.Vehicle1_image.Position = [x_pixel_Vehicle1, y_pixel_Vehicle1, 24, 24]; % update EV image with new position
            
            % Update vehicle2 image location on track
            x_Vehicle2 = x_Vehicle2 + 2.37591; % Offset by x/y position at bottom left of track
            y_Vehicle2 = y_Vehicle2 + 0.29887; % Offset by x/y position at bottom left of track
            x_pixel_Vehicle2 = app.track_xoffset - app.track_vehicle_xoffset + x_Vehicle2 * app.track_xscale; % Convert to pixel location of center of vehicle image
            y_pixel_Vehicle2 = app.track_yoffset - app.track_vehicle_yoffset + y_Vehicle2 * app.track_yscale;
            rotatedImage = imrotate(imread('EV2.png'), th_Vehicle2, "bilinear", "crop"); % Rotate image - TODO: try to find a way to rotate that looks better!
            app.Vehicle2_image.ImageSource = rotatedImage; % update EV image with new rotation
            app.Vehicle2_image.Position = [x_pixel_Vehicle2, y_pixel_Vehicle2, 24, 24]; % update EV image with new position
            
            % Update vehicle3 image location on track
            x_Vehicle3 = x_Vehicle3 + 2.37591; % Offset by x/y position at bottom left of track
            y_Vehicle3 = y_Vehicle3 + 0.29887; % Offset by x/y position at bottom left of track
            x_pixel_Vehicle3 = app.track_xoffset - app.track_vehicle_xoffset + x_Vehicle3 * app.track_xscale; % Convert to pixel location of center of vehicle image
            y_pixel_Vehicle3 = app.track_yoffset - app.track_vehicle_yoffset + y_Vehicle3 * app.track_yscale;
            rotatedImage = imrotate(imread('EV3.png'), th_Vehicle3, "bilinear", "crop"); % Rotate image - TODO: try to find a way to rotate that looks better!
            app.Vehicle3_image.ImageSource = rotatedImage; % update EV image with new rotation
            app.Vehicle3_image.Position = [x_pixel_Vehicle3, y_pixel_Vehicle3, 24, 24]; % update EV image with new position
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupfcn(app)
            % Put the GUI in the center of the screen
            movegui(app.ECE756EV3GUIUIFigure,'center');
            
            % Initialize track
            app.Vehicle1_image = uiimage(app.ECE756EV3GUIUIFigure, "ImageSource", imread('EV1.png'), "Position", [500, 500, 24, 24]);
            app.Vehicle2_image = uiimage(app.ECE756EV3GUIUIFigure, "ImageSource", imread('EV2.png'), "Position", [550, 500, 24, 24]);
            app.Vehicle3_image = uiimage(app.ECE756EV3GUIUIFigure, "ImageSource", imread('EV3.png'), "Position", [600, 500, 24, 24]);
            
            % Create variable tags for communication between GUI and
            % simulation
            app.Vehicle1_X.Tag                 = 'Vehicle1_X';
            app.Vehicle1_Y.Tag                 = 'Vehicle1_Y';
            app.Vehicle1_TH.Tag                = 'Vehicle1_TH';
            app.Vehicle1_C1.Tag                = 'Vehicle1_C1';
            app.Vehicle1_C2.Tag                = 'Vehicle1_C2';
            app.Vehicle1_C3.Tag                = 'Vehicle1_C3';
            app.Vehicle1_RightRLIGauge.Tag     = 'Vehicle1_RightRLI';
            app.Vehicle1_LeftRLIGauge.Tag      = 'Vehicle1_LeftRLI';
            app.Vehicle1_UltrasoundcmGauge.Tag = 'Vehicle1_Ultrasound';
            app.Vehicle1_BatterymVGauge.Tag    = 'Vehicle1_Battery';
            
            app.Vehicle2_X.Tag                 = 'Vehicle2_X';
            app.Vehicle2_Y.Tag                 = 'Vehicle2_Y';
            app.Vehicle2_TH.Tag                = 'Vehicle2_TH';
            app.Vehicle2_C1.Tag                = 'Vehicle2_C1';
            app.Vehicle2_C2.Tag                = 'Vehicle2_C2';
            app.Vehicle2_C3.Tag                = 'Vehicle2_C3';
            app.Vehicle2_RightRLIGauge.Tag     = 'Vehicle2_RightRLI';
            app.Vehicle2_LeftRLIGauge.Tag      = 'Vehicle2_LeftRLI';
            app.Vehicle2_UltrasoundcmGauge.Tag = 'Vehicle2_Ultrasound';
            app.Vehicle2_BatterymVGauge.Tag    = 'Vehicle2_Battery';
            
            app.Vehicle3_X.Tag                 = 'Vehicle3_X';
            app.Vehicle3_Y.Tag                 = 'Vehicle3_Y';
            app.Vehicle3_TH.Tag                = 'Vehicle3_TH';
            app.Vehicle3_C1.Tag                = 'Vehicle3_C1';
            app.Vehicle3_C2.Tag                = 'Vehicle3_C2';
            app.Vehicle3_C3.Tag                = 'Vehicle3_C3';
            app.Vehicle3_RightRLIGauge.Tag     = 'Vehicle3_RightRLI';
            app.Vehicle3_LeftRLIGauge.Tag      = 'Vehicle3_LeftRLI';
            app.Vehicle3_UltrasoundcmGauge.Tag = 'Vehicle3_Ultrasound';
            app.Vehicle3_BatterymVGauge.Tag    = 'Vehicle3_Battery';
            
            app.Vehicle4_X.Tag                 = 'Vehicle4_X';
            app.Vehicle4_Y.Tag                 = 'Vehicle4_Y';
            app.Vehicle4_TH.Tag                = 'Vehicle4_TH';
            app.Vehicle4_C1.Tag                = 'Vehicle4_C1';
            app.Vehicle4_C2.Tag                = 'Vehicle4_C2';
            app.Vehicle4_C3.Tag                = 'Vehicle4_C3';
            app.Vehicle4_RightRLIGauge.Tag     = 'Vehicle4_RightRLI';
            app.Vehicle4_LeftRLIGauge.Tag      = 'Vehicle4_LeftRLI';
            app.Vehicle4_UltrasoundcmGauge.Tag = 'Vehicle4_Ultrasound';
            app.Vehicle4_BatterymVGauge.Tag    = 'Vehicle4_Battery';
            
            % Initialize simulation control variables
            %assignin('base', 'Vehicle1_X_Place',   0);
            %assignin('base', 'Vehicle1_Y_Place',   0);
            %assignin('base', 'Vehicle1_TH_Place',  0);
            %assignin('base', 'Vehicle1_Pos_Reset', 0);
            %assignin('base', 'Vehicle2_X_Place',   0);
            %assignin('base', 'Vehicle2_Y_Place',   0);
            %assignin('base', 'Vehicle2_TH_Place',  0);
            %assignin('base', 'Vehicle2_Pos_Reset', 0);
            %assignin('base', 'Vehicle3_X_Place',   0);
            %assignin('base', 'Vehicle3_Y_Place',   0);
            %assignin('base', 'Vehicle3_TH_Place',  0);
            %assignin('base', 'Vehicle3_Pos_Reset', 0);
            %assignin('base', 'Vehicle4_X_Place',   0);
            %assignin('base', 'Vehicle4_Y_Place',   0);
            %assignin('base', 'Vehicle4_TH_Place',  0);
            %assignin('base', 'Vehicle4_Pos_Reset', 0);
            
            % Write variable to workspace - Useful for debugging
            %assignin('base', 'gui', app);
            
            % Start callback timer, copied from 2020 iSpace code
            app.uiTimer.TimerFcn = @(~, ~)timerTick(app);
            start(app.uiTimer);
        end

        % Button pushed function: StartSimulinkButton
        function runSimulation(app, event)
            disp('Running simulation')
            % Check if an enabled vehicle is selected. There needs to be a
            % leader selected which is also enabled for simulation.
            readyToRun = (strcmp(app.Vehicle1_LeaderButton.Enable, 'on') && app.Vehicle1_LeaderButton.Value) || ...
                         (strcmp(app.Vehicle2_LeaderButton.Enable, 'on') && app.Vehicle2_LeaderButton.Value) || ...
                         (strcmp(app.Vehicle3_LeaderButton.Enable, 'on') && app.Vehicle3_LeaderButton.Value) || ...
                         (strcmp(app.Vehicle4_LeaderButton.Enable, 'on') && app.Vehicle4_LeaderButton.Value);
            
            if readyToRun
                % Run Simulink
                simulinkModel = 'full_sim';
                open_system(simulinkModel);
                set_param(simulinkModel,'SimulationCommand','start');
                %app.rendering = true;
            else
                msgbox('Select an enabled vehicle as the leader before starting Simulink.')
            end
        end

        % Button pushed function: StopSimulinkButton
        function stopSimulation(app, event)
            disp('Stopping simulation')
            %app.rendering = false;
            simulinkModel = 'full_sim';
            set_param(simulinkModel,'SimulationCommand','stop');
        end

        % Callback function: ECE756EV3GUIUIFigure, ExitMenu
        function ECE756EV3GUIUIFigureCloseRequest(app, event)
            disp('Shutting down')
            try
                app.stopSimulation(app)
            catch
                disp('Tried to stop simulation, but simulation is not open!')
            end
            delete(app.uiTimer)
            delete(app)
        end

        % Value changed function: Vehicle1_VisibleSwitch
        function Vehicle1_VisibleSwitchValueChanged(app, event)
            % Enable/disable leader radio buttons depending on which cars
            % are enabled
            value = app.Vehicle1_VisibleSwitch.Value;
            if strcmp(value, 'Yes')
                app.Vehicle1_LeaderButton.Enable = 1;
            else
                % Make sure this leader isn't selected before disabling.
                if app.Vehicle1_LeaderButton.Value
                    app.Vehicle1_VisibleSwitch.Value = 'Yes';
                    msgbox("You can't disable a car selected as leader.")
                else
                    app.Vehicle1_LeaderButton.Enable = 0;
                end
            end
        end

        % Value changed function: Vehicle2_VisibleSwitch
        function Vehicle2_VisibleSwitchValueChanged(app, event)
            % Enable/disable leader radio buttons depending on which cars
            % are enabled
            value = app.Vehicle2_VisibleSwitch.Value;
            if strcmp(value, 'Yes')
                app.Vehicle2_LeaderButton.Enable = 1;
            else
                % Make sure this leader isn't selected before disabling.
                if app.Vehicle2_LeaderButton.Value
                    app.Vehicle2_VisibleSwitch.Value = 'Yes';
                    msgbox("You can't disable a car selected as leader.")
                else
                    app.Vehicle2_LeaderButton.Enable = 0;
                end
            end
        end

        % Value changed function: Vehicle3_VisibleSwitch
        function Vehicle3_VisibleSwitchValueChanged(app, event)
            % Enable/disable leader radio buttons depending on which cars
            % are enabled
            value = app.Vehicle3_VisibleSwitch.Value;
            if strcmp(value, 'Yes')
                app.Vehicle3_LeaderButton.Enable = 1;
            else
                % Make sure this leader isn't selected before disabling.
                if app.Vehicle3_LeaderButton.Value
                    app.Vehicle3_VisibleSwitch.Value = 'Yes';
                    msgbox("You can't disable a car selected as leader.")
                else
                    app.Vehicle3_LeaderButton.Enable = 0;
                end
            end
        end

        % Value changed function: Vehicle4_VisibleSwitch
        function Vehicle4_VisibleSwitchValueChanged(app, event)
            % Enable/disable leader radio buttons depending on which cars
            % are enabled
            value = app.Vehicle4_VisibleSwitch.Value;
            if strcmp(value, 'Yes')
                app.Vehicle4_LeaderButton.Enable = 1;
            else
                % Make sure this leader isn't selected before disabling.
                if app.Vehicle4_LeaderButton.Value
                    app.Vehicle4_VisibleSwitch.Value = 'Yes';
                    msgbox("You can't disable a car selected as leader.")
                else
                    app.Vehicle4_LeaderButton.Enable = 0;
                end
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            msgbox({'ECE756 EV3 Simulation Sandbox';'Spring 2021';'Demonstration of line following, platooning, and parking control logic';'';'David Fleissner';'Thomas Thorne' } ,'About','modal')
        end

        % Menu selected function: ManualMenu
        function ManualMenuSelected(app, event)
            msgbox('TODO')
        end

        % Button pushed function: StartAllCarsButton
        function StartAllCarsButtonPushed(app, event)
            msgbox('TODO')
        end

        % Button pushed function: StopAllCarsButton
        function StopAllCarsButtonPushed(app, event)
            msgbox('TODO')
        end

        % Button pushed function: DebugButton
        function DebugButtonPushed(app, event)
            %app.delete_me = app.delete_me + 10;
            %imrot = imrotate(app.test_EV1_img, app.delete_me, "bilinear", "crop");
            %uiimage(app.ECE756EV3GUIUIFigure, "ImageSource", imrot, "Position", [500, 500, 24, 24]);
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            x = event.IntersectionPoint(1);
            y = event.IntersectionPoint(2);
            fprintf('x = %.2f | y = %.2f\n', x, y);
            if app.placingVehicle1
                assignin('base', 'Vehicle1_X_Place',  x); % Write new positions to workspace
                assignin('base', 'Vehicle1_Y_Place',  y);
                assignin('base', 'Vehicle1_TH_Place', 0);
                assignin('base', 'Vehicle1_Pos_Reset', 1); % Triggers a position update!
                app.Vehicle1_X.Value = x;
                app.Vehicle1_Y.Value = y;
                app.placingVehicle1 = false;
            elseif app.placingVehicle2
                assignin('base', 'Vehicle2_X_Place',  x);
                assignin('base', 'Vehicle2_Y_Place',  y);
                assignin('base', 'Vehicle2_TH_Place', 0);
                assignin('base', 'Vehicle2_Pos_Reset', 1);
                app.Vehicle2_X.Value = x;
                app.Vehicle2_Y.Value = y;
                app.placingVehicle2 = false;
            elseif app.placingVehicle3
                assignin('base', 'Vehicle3_X_Place',  x);
                assignin('base', 'Vehicle3_Y_Place',  y);
                assignin('base', 'Vehicle3_TH_Place', 0);
                assignin('base', 'Vehicle3_Pos_Reset', 1);
                app.Vehicle3_X.Value = x;
                app.Vehicle3_Y.Value = y;
                app.placingVehicle3 = false;
            elseif app.placingVehicle4
                assignin('base', 'Vehicle4_X_Place',  x);
                assignin('base', 'Vehicle4_Y_Place',  y);
                assignin('base', 'Vehicle4_TH_Place', 0);
                assignin('base', 'Vehicle4_Pos_Reset', 1);
                app.Vehicle4_X.Value = x;
                app.Vehicle4_Y.Value = y;
                app.placingVehicle4 = false;
            end
        end

        % Button pushed function: PlaceVehicle1Button
        function PlaceVehicle1ButtonPushed(app, event)
            app.placingVehicle1 = true;
        end

        % Button pushed function: PlaceVehicle2Button
        function PlaceVehicle2ButtonPushed(app, event)
            app.placingVehicle2 = true;
        end

        % Button pushed function: PlaceVehicle3Button
        function PlaceVehicle3ButtonPushed(app, event)
            app.placingVehicle3 = true;
        end

        % Button pushed function: PlaceVehicle4Button
        function PlaceVehicle4ButtonPushed(app, event)
            app.placingVehicle4 = true;
        end

        % Callback function
        function ButtonPushed(app, event)
            app.delete_me = app.delete_me + 1;
            app.Label.Text = string(app.delete_me);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ECE756EV3GUIUIFigure = uifigure('Visible', 'off');
            app.ECE756EV3GUIUIFigure.Name = 'ECE756 EV3 GUI';
            app.ECE756EV3GUIUIFigure.CloseRequestFcn = createCallbackFcn(app, @ECE756EV3GUIUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.ECE756EV3GUIUIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ECE756EV3GUIUIFigureCloseRequest, true);
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.ECE756EV3GUIUIFigure);
            app.HelpMenu.Text = 'Help';
            app.ManualMenu = uimenu(app.HelpMenu);
            app.ManualMenu.MenuSelectedFcn = createCallbackFcn(app, @ManualMenuSelected, true);
            app.ManualMenu.Text = 'Manual';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.TrackImage = uiimage(app.ECE756EV3GUIUIFigure);
            app.TrackImage.ImageSource = 'map.png';
            app.Vehicle1_RedPanel = uipanel(app.ECE756EV3GUIUIFigure);
            app.Vehicle1_RedPanel.Title = 'Vehicle 1 (Red)';
            app.Vehicle1_PositionPanel = uipanel(app.Vehicle1_RedPanel);
            app.Vehicle1_PositionPanel.Title = 'Vehicle 1 Position';
            app.Vehicle1_X = uieditfield(app.Vehicle1_PositionPanel, 'numeric');
            app.Vehicle1_X.ValueDisplayFormat = '%5.1f';
            app.Vehicle1_XmmLabel = uilabel(app.Vehicle1_PositionPanel);
            app.Vehicle1_XmmLabel.Text = 'X (mm)';
            app.Vehicle1_Y = uieditfield(app.Vehicle1_PositionPanel, 'numeric');
            app.Vehicle1_Y.ValueDisplayFormat = '%5.1f';
            app.Vehicle1_YmmLabel = uilabel(app.Vehicle1_PositionPanel);
            app.Vehicle1_YmmLabel.Text = 'Y (mm)';
            app.Vehicle1_TH = uieditfield(app.Vehicle1_PositionPanel, 'numeric');
            app.Vehicle1_TH.ValueDisplayFormat = '%3.1f';
            app.Vehicle1_YawdegLabel = uilabel(app.Vehicle1_PositionPanel);
            app.Vehicle1_YawdegLabel.Text = 'Yaw (deg)';
            app.Vehicle1_SensorsPanel = uipanel(app.Vehicle1_RedPanel);
            app.Vehicle1_SensorsPanel.Title = 'Vehicle 1 Sensors';
            app.BatterymVGauge_4Label = uilabel(app.Vehicle1_SensorsPanel);
            app.BatterymVGauge_4Label.Text = 'Battery (mV)';
            app.Vehicle1_BatterymVGauge = uigauge(app.Vehicle1_SensorsPanel, 'semicircular');
            app.Vehicle1_BatterymVGauge.Limits = [7000 8500];
            app.Vehicle1_BatterymVGauge.MajorTicks = [7000 7750 8500];
            app.LeftRLIGauge_4Label = uilabel(app.Vehicle1_SensorsPanel);
            app.LeftRLIGauge_4Label.Text = 'Left RLI';
            app.Vehicle1_LeftRLIGauge = uigauge(app.Vehicle1_SensorsPanel, 'linear');
            app.RightRLIGauge_4Label = uilabel(app.Vehicle1_SensorsPanel);
            app.RightRLIGauge_4Label.Text = 'Right RLI';
            app.Vehicle1_RightRLIGauge = uigauge(app.Vehicle1_SensorsPanel, 'linear');
            app.UltrasoundcmGauge_4Label = uilabel(app.Vehicle1_SensorsPanel);
            app.UltrasoundcmGauge_4Label.Text = 'Ultrasound (cm)';
            app.Vehicle1_UltrasoundcmGauge = uigauge(app.Vehicle1_SensorsPanel, 'linear');
            app.Vehicle1_UltrasoundcmGauge.Limits = [0 255];
            app.Vehicle1_UltrasoundcmGauge.MajorTicks = [0 64 128 192 255];
            app.Vehicle1_PerformancePanel = uipanel(app.Vehicle1_RedPanel);
            app.Vehicle1_PerformancePanel.Title = 'Vehicle 1 Performance';
            app.Vehicle1_C1 = uieditfield(app.Vehicle1_PerformancePanel, 'numeric');
            app.Vehicle1_C1.ValueDisplayFormat = '%5.1f';
            app.Vehicle1_C1.Editable = 'off';
            app.Vehicle1_C1LapTimeLabel = uilabel(app.Vehicle1_PerformancePanel);
            app.Vehicle1_C1LapTimeLabel.Text = 'C1 - Tracking Error';
            app.Vehicle1_C3 = uieditfield(app.Vehicle1_PerformancePanel, 'numeric');
            app.Vehicle1_C3.ValueDisplayFormat = '%3.1f';
            app.Vehicle1_C3.Editable = 'off';
            app.Vehicle1_C3SmoothnessLabel = uilabel(app.Vehicle1_PerformancePanel);
            app.Vehicle1_C3SmoothnessLabel.Text = 'C3 - Platooning';
            app.Vehicle1_C2 = uieditfield(app.Vehicle1_PerformancePanel, 'numeric');
            app.Vehicle1_C2.ValueDisplayFormat = '%3.1f';
            app.Vehicle1_C2.Editable = 'off';
            app.Vehicle1_C2TrackingErrorLabel = uilabel(app.Vehicle1_PerformancePanel);
            app.Vehicle1_C2TrackingErrorLabel.Text = 'C2 - Smoothness';
            app.Vehicle1_ControlsPanel = uipanel(app.Vehicle1_RedPanel);
            app.Vehicle1_ControlsPanel.Enable = 'off';
            app.Vehicle1_ControlsPanel.Title = 'Vehicle 1 Controls';
            app.VisibleSwitch_3Label_2 = uilabel(app.Vehicle1_ControlsPanel);
            app.VisibleSwitch_3Label_2.Text = 'Visible';
            app.Vehicle1_VisibleSwitch = uiswitch(app.Vehicle1_ControlsPanel, 'slider');
            app.Vehicle1_VisibleSwitch.Items = {'No', 'Yes'};
            app.Vehicle1_VisibleSwitch.ValueChangedFcn = createCallbackFcn(app, @Vehicle1_VisibleSwitchValueChanged, true);
            app.Vehicle1_VisibleSwitch.Value = 'Yes';
            app.Vehicle1_DriveButton = uibutton(app.Vehicle1_ControlsPanel, 'push');
            app.Vehicle1_DriveButton.Text = 'Drive';
            app.Vehicle1_StopButton = uibutton(app.Vehicle1_ControlsPanel, 'push');
            app.Vehicle1_StopButton.Text = 'Stop';
            app.Vehicle1_ParkButton = uibutton(app.Vehicle1_ControlsPanel, 'push');
            app.Vehicle1_ParkButton.Text = 'Park';
            app.Vehicle2_GreenPanel = uipanel(app.ECE756EV3GUIUIFigure);
            app.Vehicle2_GreenPanel.Title = 'Vehicle 2 (Green)';
            app.Vehicle2_PositionPanel = uipanel(app.Vehicle2_GreenPanel);
            app.Vehicle2_PositionPanel.Title = 'Vehicle 2 Position';
            app.Vehicle2_X = uieditfield(app.Vehicle2_PositionPanel, 'numeric');
            app.Vehicle2_X.ValueDisplayFormat = '%5.1f';
            app.Vehicle2_XmmLabel = uilabel(app.Vehicle2_PositionPanel);
            app.Vehicle2_XmmLabel.Text = 'X (mm)';
            app.Vehicle2_Y = uieditfield(app.Vehicle2_PositionPanel, 'numeric');
            app.Vehicle2_Y.ValueDisplayFormat = '%5.1f';
            app.Vehicle2_YmmLabel = uilabel(app.Vehicle2_PositionPanel);
            app.Vehicle2_YmmLabel.Text = 'Y (mm)';
            app.Vehicle2_TH = uieditfield(app.Vehicle2_PositionPanel, 'numeric');
            app.Vehicle2_TH.ValueDisplayFormat = '%3.1f';
            app.Vehicle2_YawdegLabel = uilabel(app.Vehicle2_PositionPanel);
            app.Vehicle2_YawdegLabel.Text = 'Yaw (deg)';
            app.Vehicle2_SensorsPanel = uipanel(app.Vehicle2_GreenPanel);
            app.Vehicle2_SensorsPanel.Title = 'Vehicle 2 Sensors';
            app.BatterymVGauge_5Label = uilabel(app.Vehicle2_SensorsPanel);
            app.BatterymVGauge_5Label.Text = 'Battery (mV)';
            app.Vehicle2_BatterymVGauge = uigauge(app.Vehicle2_SensorsPanel, 'semicircular');
            app.Vehicle2_BatterymVGauge.Limits = [7000 8500];
            app.Vehicle2_BatterymVGauge.MajorTicks = [7000 7750 8500];
            app.LeftRLIGauge_5Label = uilabel(app.Vehicle2_SensorsPanel);
            app.LeftRLIGauge_5Label.Text = 'Left RLI';
            app.Vehicle2_LeftRLIGauge = uigauge(app.Vehicle2_SensorsPanel, 'linear');
            app.RightRLIGauge_5Label = uilabel(app.Vehicle2_SensorsPanel);
            app.RightRLIGauge_5Label.Text = 'Right RLI';
            app.Vehicle2_RightRLIGauge = uigauge(app.Vehicle2_SensorsPanel, 'linear');
            app.UltrasoundcmGauge_5Label = uilabel(app.Vehicle2_SensorsPanel);
            app.UltrasoundcmGauge_5Label.Text = 'Ultrasound (cm)';
            app.Vehicle2_UltrasoundcmGauge = uigauge(app.Vehicle2_SensorsPanel, 'linear');
            app.Vehicle2_UltrasoundcmGauge.Limits = [0 255];
            app.Vehicle2_UltrasoundcmGauge.MajorTicks = [0 64 128 192 255];
            app.Vehicle2_PerformancePanel = uipanel(app.Vehicle2_GreenPanel);
            app.Vehicle2_PerformancePanel.Title = 'Vehicle 2 Performance';
            app.Vehicle2_C1 = uieditfield(app.Vehicle2_PerformancePanel, 'numeric');
            app.Vehicle2_C1.ValueDisplayFormat = '%5.1f';
            app.Vehicle2_C1.Editable = 'off';
            app.Vehicle2_C1LapTimeLabel = uilabel(app.Vehicle2_PerformancePanel);
            app.Vehicle2_C1LapTimeLabel.Text = 'C1 - Tracking Error';
            app.Vehicle2_C3 = uieditfield(app.Vehicle2_PerformancePanel, 'numeric');
            app.Vehicle2_C3.ValueDisplayFormat = '%3.1f';
            app.Vehicle2_C3.Editable = 'off';
            app.Vehicle2_C3SmoothnessLabel = uilabel(app.Vehicle2_PerformancePanel);
            app.Vehicle2_C3SmoothnessLabel.Text = 'C3 - Platooning';
            app.Vehicle2_C2 = uieditfield(app.Vehicle2_PerformancePanel, 'numeric');
            app.Vehicle2_C2.ValueDisplayFormat = '%3.1f';
            app.Vehicle2_C2.Editable = 'off';
            app.Vehicle2_C2TrackingErrorLabel = uilabel(app.Vehicle2_PerformancePanel);
            app.Vehicle2_C2TrackingErrorLabel.Text = 'C2 - Smoothness';
            app.Vehicle2_ControlsPanel = uipanel(app.Vehicle2_GreenPanel);
            app.Vehicle2_ControlsPanel.Enable = 'off';
            app.Vehicle2_ControlsPanel.Title = 'Vehicle 2 Controls';
            app.VisibleSwitch_3Label_3 = uilabel(app.Vehicle2_ControlsPanel);
            app.VisibleSwitch_3Label_3.Text = 'Visible';
            app.Vehicle2_VisibleSwitch = uiswitch(app.Vehicle2_ControlsPanel, 'slider');
            app.Vehicle2_VisibleSwitch.Items = {'No', 'Yes'};
            app.Vehicle2_VisibleSwitch.ValueChangedFcn = createCallbackFcn(app, @Vehicle2_VisibleSwitchValueChanged, true);
            app.Vehicle2_VisibleSwitch.Value = 'No';
            app.Vehicle2_DriveButton = uibutton(app.Vehicle2_ControlsPanel, 'push');
            app.Vehicle2_DriveButton.Text = 'Drive';
            app.Vehicle2_StopButton = uibutton(app.Vehicle2_ControlsPanel, 'push');
            app.Vehicle2_StopButton.Text = 'Stop';
            app.Vehicle2_ParkButton = uibutton(app.Vehicle2_ControlsPanel, 'push');
            app.Vehicle2_ParkButton.Text = 'Park';
            app.Vehicle3_BluePanel = uipanel(app.ECE756EV3GUIUIFigure);
            app.Vehicle3_BluePanel.Title = 'Vehicle 3 (Blue)';
            app.Vehicle3_PositionPanel = uipanel(app.Vehicle3_BluePanel);
            app.Vehicle3_PositionPanel.Title = 'Vehicle 3 Position';
            app.Vehicle3_X = uieditfield(app.Vehicle3_PositionPanel, 'numeric');
            app.Vehicle3_X.ValueDisplayFormat = '%5.1f';
            app.Vehicle3_XmmLabel = uilabel(app.Vehicle3_PositionPanel);
            app.Vehicle3_XmmLabel.Text = 'X (mm)';
            app.Vehicle3_Y = uieditfield(app.Vehicle3_PositionPanel, 'numeric');
            app.Vehicle3_Y.ValueDisplayFormat = '%5.1f';
            app.Vehicle3_YmmLabel = uilabel(app.Vehicle3_PositionPanel);
            app.Vehicle3_YmmLabel.Text = 'Y (mm)';
            app.Vehicle3_TH = uieditfield(app.Vehicle3_PositionPanel, 'numeric');
            app.Vehicle3_TH.ValueDisplayFormat = '%3.1f';
            app.Vehicle3_YawdegLabel = uilabel(app.Vehicle3_PositionPanel);
            app.Vehicle3_YawdegLabel.Text = 'Yaw (deg)';
            app.Vehicle3_SensorsPanel = uipanel(app.Vehicle3_BluePanel);
            app.Vehicle3_SensorsPanel.Title = 'Vehicle 3 Sensors';
            app.BatterymVGauge_6Label = uilabel(app.Vehicle3_SensorsPanel);
            app.BatterymVGauge_6Label.Text = 'Battery (mV)';
            app.Vehicle3_BatterymVGauge = uigauge(app.Vehicle3_SensorsPanel, 'semicircular');
            app.Vehicle3_BatterymVGauge.Limits = [7000 8500];
            app.Vehicle3_BatterymVGauge.MajorTicks = [7000 7750 8500];
            app.LeftRLIGauge_6Label = uilabel(app.Vehicle3_SensorsPanel);
            app.LeftRLIGauge_6Label.Text = 'Left RLI';
            app.Vehicle3_LeftRLIGauge = uigauge(app.Vehicle3_SensorsPanel, 'linear');
            app.RightRLIGauge_6Label = uilabel(app.Vehicle3_SensorsPanel);
            app.RightRLIGauge_6Label.Text = 'Right RLI';
            app.Vehicle3_RightRLIGauge = uigauge(app.Vehicle3_SensorsPanel, 'linear');
            app.UltrasoundcmGauge_6Label = uilabel(app.Vehicle3_SensorsPanel);
            app.UltrasoundcmGauge_6Label.Text = 'Ultrasound (cm)';
            app.Vehicle3_UltrasoundcmGauge = uigauge(app.Vehicle3_SensorsPanel, 'linear');
            app.Vehicle3_UltrasoundcmGauge.Limits = [0 255];
            app.Vehicle3_UltrasoundcmGauge.MajorTicks = [0 64 128 192 255];
            app.Vehicle3_PerformancePanel = uipanel(app.Vehicle3_BluePanel);
            app.Vehicle3_PerformancePanel.Title = 'Vehicle 3 Performance';
            app.Vehicle3_C1 = uieditfield(app.Vehicle3_PerformancePanel, 'numeric');
            app.Vehicle3_C1.ValueDisplayFormat = '%5.1f';
            app.Vehicle3_C1.Editable = 'off';
            app.Vehicle3_C1LapTimeLabel = uilabel(app.Vehicle3_PerformancePanel);
            app.Vehicle3_C1LapTimeLabel.Text = 'C1 - Tracking Error';
            app.Vehicle3_C3 = uieditfield(app.Vehicle3_PerformancePanel, 'numeric');
            app.Vehicle3_C3.ValueDisplayFormat = '%3.1f';
            app.Vehicle3_C3.Editable = 'off';
            app.Vehicle3_C3SmoothnessLabel = uilabel(app.Vehicle3_PerformancePanel);
            app.Vehicle3_C3SmoothnessLabel.Text = 'C3 - Platooning';
            app.Vehicle3_C2 = uieditfield(app.Vehicle3_PerformancePanel, 'numeric');
            app.Vehicle3_C2.ValueDisplayFormat = '%3.1f';
            app.Vehicle3_C2.Editable = 'off';
            app.Vehicle3_C2TrackingErrorLabel = uilabel(app.Vehicle3_PerformancePanel);
            app.Vehicle3_C2TrackingErrorLabel.Text = 'C2 - Smoothness';
            app.Vehicle3_ControlsPanel = uipanel(app.Vehicle3_BluePanel);
            app.Vehicle3_ControlsPanel.Enable = 'off';
            app.Vehicle3_ControlsPanel.Title = 'Vehicle 3 Controls';
            app.VisibleSwitch_3Label_4 = uilabel(app.Vehicle3_ControlsPanel);
            app.VisibleSwitch_3Label_4.Text = 'Visible';
            app.Vehicle3_VisibleSwitch = uiswitch(app.Vehicle3_ControlsPanel, 'slider');
            app.Vehicle3_VisibleSwitch.Items = {'No', 'Yes'};
            app.Vehicle3_VisibleSwitch.ValueChangedFcn = createCallbackFcn(app, @Vehicle3_VisibleSwitchValueChanged, true);
            app.Vehicle3_VisibleSwitch.Value = 'No';
            app.Vehicle3_DriveButton = uibutton(app.Vehicle3_ControlsPanel, 'push');
            app.Vehicle3_DriveButton.Text = 'Drive';
            app.Vehicle3_StopButton = uibutton(app.Vehicle3_ControlsPanel, 'push');
            app.Vehicle3_StopButton.Text = 'Stop';
            app.Vehicle3_ParkButton = uibutton(app.Vehicle3_ControlsPanel, 'push');
            app.Vehicle3_ParkButton.Text = 'Park';
            app.Vehicle4_PurplePanel = uipanel(app.ECE756EV3GUIUIFigure);
            app.Vehicle4_PurplePanel.Title = 'Vehicle 4 (Purple)';
            app.Vehicle4PositionPanel = uipanel(app.Vehicle4_PurplePanel);
            app.Vehicle4PositionPanel.Title = 'Vehicle 4 Position';
            app.Vehicle4_X = uieditfield(app.Vehicle4PositionPanel, 'numeric');
            app.Vehicle4_X.ValueDisplayFormat = '%5.1f';
            app.Vehicle4_XmmLabel = uilabel(app.Vehicle4PositionPanel);
            app.Vehicle4_XmmLabel.Text = 'X (mm)';
            app.Vehicle4_Y = uieditfield(app.Vehicle4PositionPanel, 'numeric');
            app.Vehicle4_Y.ValueDisplayFormat = '%5.1f';
            app.Vehicle4_YmmLabel = uilabel(app.Vehicle4PositionPanel);
            app.Vehicle4_YmmLabel.Text = 'Y (mm)';
            app.Vehicle4_TH = uieditfield(app.Vehicle4PositionPanel, 'numeric');
            app.Vehicle4_TH.ValueDisplayFormat = '%3.1f';
            app.Vehicle4_YawdegLabel = uilabel(app.Vehicle4PositionPanel);
            app.Vehicle4_YawdegLabel.Text = 'Yaw (deg)';
            app.Vehicle4SensorsPanel = uipanel(app.Vehicle4_PurplePanel);
            app.Vehicle4SensorsPanel.Title = 'Vehicle 4 Sensors';
            app.BatterymVGauge_7Label = uilabel(app.Vehicle4SensorsPanel);
            app.BatterymVGauge_7Label.Text = 'Battery (mV)';
            app.Vehicle4_BatterymVGauge = uigauge(app.Vehicle4SensorsPanel, 'semicircular');
            app.Vehicle4_BatterymVGauge.Limits = [7000 8500];
            app.Vehicle4_BatterymVGauge.MajorTicks = [7000 7750 8500];
            app.LeftRLIGauge_7Label = uilabel(app.Vehicle4SensorsPanel);
            app.LeftRLIGauge_7Label.Text = 'Left RLI';
            app.Vehicle4_LeftRLIGauge = uigauge(app.Vehicle4SensorsPanel, 'linear');
            app.RightRLIGauge_7Label = uilabel(app.Vehicle4SensorsPanel);
            app.RightRLIGauge_7Label.Text = 'Right RLI';
            app.Vehicle4_RightRLIGauge = uigauge(app.Vehicle4SensorsPanel, 'linear');
            app.UltrasoundcmGauge_7Label = uilabel(app.Vehicle4SensorsPanel);
            app.UltrasoundcmGauge_7Label.Text = 'Ultrasound (cm)';
            app.Vehicle4_UltrasoundcmGauge = uigauge(app.Vehicle4SensorsPanel, 'linear');
            app.Vehicle4_UltrasoundcmGauge.Limits = [0 255];
            app.Vehicle4_UltrasoundcmGauge.MajorTicks = [0 64 128 192 255];
            app.Vehicle4_PerformancePanel = uipanel(app.Vehicle4_PurplePanel);
            app.Vehicle4_PerformancePanel.Title = 'Vehicle 4 Performance';
            app.Vehicle4_C1 = uieditfield(app.Vehicle4_PerformancePanel, 'numeric');
            app.Vehicle4_C1.ValueDisplayFormat = '%5.1f';
            app.Vehicle4_C1.Editable = 'off';
            app.Vehicle4_C1LapTimeLabel = uilabel(app.Vehicle4_PerformancePanel);
            app.Vehicle4_C1LapTimeLabel.Text = 'C1 - Tracking Error';
            app.Vehicle4_C3 = uieditfield(app.Vehicle4_PerformancePanel, 'numeric');
            app.Vehicle4_C3.ValueDisplayFormat = '%3.1f';
            app.Vehicle4_C3.Editable = 'off';
            app.Vehicle4_C3SmoothnessLabel = uilabel(app.Vehicle4_PerformancePanel);
            app.Vehicle4_C3SmoothnessLabel.Text = 'C3 - Platooning';
            app.Vehicle4_C2 = uieditfield(app.Vehicle4_PerformancePanel, 'numeric');
            app.Vehicle4_C2.ValueDisplayFormat = '%3.1f';
            app.Vehicle4_C2.Editable = 'off';
            app.Vehicle4_C2TrackingErrorLabel = uilabel(app.Vehicle4_PerformancePanel);
            app.Vehicle4_C2TrackingErrorLabel.Text = 'C2 - Smoothness';
            app.Vehicle4_ControlsPanel = uipanel(app.Vehicle4_PurplePanel);
            app.Vehicle4_ControlsPanel.Enable = 'off';
            app.Vehicle4_ControlsPanel.Title = 'Vehicle 4 Controls';
            app.VisibleSwitch_3Label_5 = uilabel(app.Vehicle4_ControlsPanel);
            app.VisibleSwitch_3Label_5.Text = 'Visible';
            app.Vehicle4_VisibleSwitch = uiswitch(app.Vehicle4_ControlsPanel, 'slider');
            app.Vehicle4_VisibleSwitch.Items = {'No', 'Yes'};
            app.Vehicle4_VisibleSwitch.ValueChangedFcn = createCallbackFcn(app, @Vehicle4_VisibleSwitchValueChanged, true);
            app.Vehicle4_VisibleSwitch.Value = 'No';
            app.Vehicle4_DriveButton = uibutton(app.Vehicle4_ControlsPanel, 'push');
            app.Vehicle4_DriveButton.Text = 'Drive';
            app.Vehicle4_StopButton = uibutton(app.Vehicle4_ControlsPanel, 'push');
            app.Vehicle4_StopButton.Text = 'Stop';
            app.Vehicle4_ParkButton = uibutton(app.Vehicle4_ControlsPanel, 'push');
            app.Vehicle4_ParkButton.Text = 'Park';
            app.GeneralControlsPanel = uipanel(app.ECE756EV3GUIUIFigure);
            app.GeneralControlsPanel.Title = 'General Controls';
            app.SimulinkPanel = uipanel(app.GeneralControlsPanel);
            app.SimulinkPanel.Title = 'Simulink';
            app.StartSimulinkButton = uibutton(app.SimulinkPanel, 'push');
            app.StartSimulinkButton.ButtonPushedFcn = createCallbackFcn(app, @runSimulation, true);
            app.StartSimulinkButton.Text = 'Start Simulink';
            app.StopSimulinkButton = uibutton(app.SimulinkPanel, 'push');
            app.StopSimulinkButton.ButtonPushedFcn = createCallbackFcn(app, @stopSimulation, true);
            app.StopSimulinkButton.Text = 'Stop Simulink';
            app.LeaderSelectionButtonGroup = uibuttongroup(app.GeneralControlsPanel);
            app.LeaderSelectionButtonGroup.Enable = 'off';
            app.LeaderSelectionButtonGroup.Title = 'Leader Selection';
            app.Vehicle1_LeaderButton = uiradiobutton(app.LeaderSelectionButtonGroup);
            app.Vehicle1_LeaderButton.Text = 'Vehicle 1 (Red)';
            app.Vehicle1_LeaderButton.Value = true;
            app.Vehicle2_LeaderButton = uiradiobutton(app.LeaderSelectionButtonGroup);
            app.Vehicle2_LeaderButton.Enable = 'off';
            app.Vehicle2_LeaderButton.Text = 'Vehicle 2 (Green)';
            app.Vehicle3_LeaderButton = uiradiobutton(app.LeaderSelectionButtonGroup);
            app.Vehicle3_LeaderButton.Enable = 'off';
            app.Vehicle3_LeaderButton.Text = 'Vehicle 3 (Blue)';
            app.Vehicle4_LeaderButton = uiradiobutton(app.LeaderSelectionButtonGroup);
            app.Vehicle4_LeaderButton.Enable = 'off';
            app.Vehicle4_LeaderButton.Text = 'Vehicle 4 (Purple)';
            app.NoteallotherenabledvehiclesarefollowersLabel = uilabel(app.GeneralControlsPanel);
            app.NoteallotherenabledvehiclesarefollowersLabel.Text = {'Note: all other enabled'; 'vehicles are followers'};
            app.DebugButton = uibutton(app.GeneralControlsPanel, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @DebugButtonPushed, true);
            app.DebugButton.Enable = 'off';
            app.DebugButton.Visible = 'off';
            app.DebugButton.Text = 'Debug';
            app.GeneralControlsPanel_2 = uipanel(app.GeneralControlsPanel);
            app.GeneralControlsPanel_2.Enable = 'off';
            app.GeneralControlsPanel_2.Title = 'General Controls';
            app.StartAllCarsButton = uibutton(app.GeneralControlsPanel_2, 'push');
            app.StartAllCarsButton.ButtonPushedFcn = createCallbackFcn(app, @StartAllCarsButtonPushed, true);
            app.StartAllCarsButton.Text = 'Start All Cars';
            app.StopAllCarsButton = uibutton(app.GeneralControlsPanel_2, 'push');
            app.StopAllCarsButton.ButtonPushedFcn = createCallbackFcn(app, @StopAllCarsButtonPushed, true);
            app.StopAllCarsButton.Text = 'Stop All Cars';
            app.PlaceVehicle1Button = uibutton(app.GeneralControlsPanel, 'push');
            app.PlaceVehicle1Button.ButtonPushedFcn = createCallbackFcn(app, @PlaceVehicle1ButtonPushed, true);
            app.PlaceVehicle1Button.Enable = 'off';
            app.PlaceVehicle1Button.Text = 'Place Vehicle 1';
            app.PlaceVehicle2Button = uibutton(app.GeneralControlsPanel, 'push');
            app.PlaceVehicle2Button.ButtonPushedFcn = createCallbackFcn(app, @PlaceVehicle2ButtonPushed, true);
            app.PlaceVehicle2Button.Enable = 'off';
            app.PlaceVehicle2Button.Text = 'Place Vehicle 2';
            app.PlaceVehicle3Button = uibutton(app.GeneralControlsPanel, 'push');
            app.PlaceVehicle3Button.ButtonPushedFcn = createCallbackFcn(app, @PlaceVehicle3ButtonPushed, true);
            app.PlaceVehicle3Button.Enable = 'off';
            app.PlaceVehicle3Button.Text = 'Place Vehicle 3';
            app.PlaceVehicle4Button = uibutton(app.GeneralControlsPanel, 'push');
            app.PlaceVehicle4Button.ButtonPushedFcn = createCallbackFcn(app, @PlaceVehicle4ButtonPushed, true);
            app.PlaceVehicle4Button.Enable = 'off';
            app.PlaceVehicle4Button.Text = 'Place Vehicle 4';
            % Show the figure after all components are created
            app.ECE756EV3GUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EV3SimGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ECE756EV3GUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupfcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ECE756EV3GUIUIFigure)
        end
    end
end